classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        RandomNoisesTab                matlab.ui.container.Tab
        WelcomeToOurAudioEditorLabel   matlab.ui.control.Label
        ChooseWhatYouWantToDoLabel     matlab.ui.control.Label
        WhiteNoiseSoundButton          matlab.ui.control.Button
        NotficationsoundButton         matlab.ui.control.Button
        EaglesoundButton               matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
        BeepSoundButton                matlab.ui.control.Button
        CarHornSoundButton             matlab.ui.control.Button
        BirdsSoundButton               matlab.ui.control.Button
        LionSoundButton                matlab.ui.control.Button
        BeshoGamalLabel                matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        AudioFilesTab                  matlab.ui.container.Tab
        RecordingPanel                 matlab.ui.container.Panel
        RecordAudioButton              matlab.ui.control.Button
        playButton                     matlab.ui.control.Button
        EditField_2                    matlab.ui.control.NumericEditField
        EnterTimeOfRecordingLabel      matlab.ui.control.Label
        SaveAudioButton                matlab.ui.control.Button
        EnterFileNameEditField         matlab.ui.control.EditField
        EnterFileNameLabel             matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        CoustumizeAudioPanel           matlab.ui.container.Panel
        AddNoiseButton                 matlab.ui.control.Button
        RemoveNoiseButton              matlab.ui.control.Button
        PlayFileButton                 matlab.ui.control.Button
        AddEchoButton                  matlab.ui.control.Button
        SelectButton                   matlab.ui.control.Button
        RemoveEchoButton               matlab.ui.control.Button
        xButton_2                      matlab.ui.control.Button
        xButton_3                      matlab.ui.control.Button
        xButton                        matlab.ui.control.Button
        defualtButton                  matlab.ui.control.Button
        ChooseSpeedLabel               matlab.ui.control.Label
        SubplotAudioSignalButton       matlab.ui.control.Button
        ChooseVolumeLabel              matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        defualtButton_2                matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        xButton_4                      matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        SelectFileNameLabel            matlab.ui.control.Label
        EditField_3                    matlab.ui.control.EditField
        SelectFileNameEditField        matlab.ui.control.EditField
        SignalsTab                     matlab.ui.container.Tab
        MergeSignalsLabel              matlab.ui.control.Label
        signal1Label                   matlab.ui.control.Label
        signal2Label                   matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        singAudioFileButton            matlab.ui.control.Button
        Audio1Button                   matlab.ui.control.Button
        MultiplyButton                 matlab.ui.control.Button
        SubtractButton                 matlab.ui.control.Button
        EditField_4                    matlab.ui.control.EditField
        ResetButton                    matlab.ui.control.Button
        Audio2Button                   matlab.ui.control.Button
        NoteTouseitrecordaudiowithnamesingLabel  matlab.ui.control.Label
        Audio3Button                   matlab.ui.control.Button
        Audio4Button                   matlab.ui.control.Button
        LettheresultsbeasurpriseLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
       constEXE=".wav";
       Enteredfilename;
       EnteredfilenameandEXE="";
       selectedfilenameandEXE="";
        globeRecordingTime=0;
        recordsignal=0;
        Selectednumber;
        selectedfile=0;
        selectedfilepath;
        selectedfileFS;
        selectedfileoriginal;
        selectedfileoriginalFS;
         originalAudio=0;
          originalAudioFS;
          originalAudiobeforeecho=0;
          originalAudiobeforeechoFS;
          speedfactor=1;
          volumefactor=1;  
          time1;
          time2;
          sound1=0;
          sound2=0;
          sound1Fs;
          sound2Fs;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, RecordingTime)
          
        end

        % Callback function
        function StartButtonPushed(app, event)
       
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)

            delete(app.UIFigure)
              clear app
        end

        % Button pushed function: WhiteNoiseSoundButton
        function WhiteNoiseSoundButtonPushed(app, event)
            whitenoise = 0.1* rand(1,16000)*2-1;
            sound( whitenoise , 8000)
        end

        % Button pushed function: NotficationsoundButton
        function NotficationsoundButtonPushed(app, event)
            beep on 
            beep
        end

        % Callback function
        function GoBackButtonPushed(app, event)
         
        end

        % Button pushed function: RecordAudioButton
        function RecordAudioButtonPushed(app, event)
           if( app.globeRecordingTime<=0)
               app.EditField.Value ='Enter Positive number for time';
           else
               app.EditField.Value ='record started';
            recordmain=audiorecorder(44100,16,1);
             recordblocking(recordmain,  app.globeRecordingTime);
             app.recordsignal=getaudiodata(recordmain);
            app.EditField.Value ='End of record';
           end
        end

        % Callback function
        function StopRecordingButtonPushed(app, event)
            
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
           if(app.recordsignal==0)
               app.EditField.Value ='No record to play';
           else
            app.EditField.Value ='Record is playing';
            sound( app.recordsignal,44100);
           
           end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
             app.globeRecordingTime = app.EditField_2.Value;
            
        end

        % Button pushed function: EaglesoundButton
        function EaglesoundButtonPushed(app, event)
             [birdSound, Fs] = audioread('mixkit-big-wild-eagle-calling-70.wav');
            sound(birdSound, Fs);
        end

        % Button pushed function: SaveAudioButton
        function SaveAudioButtonPushed(app, event)
            if(app.EnteredfilenameandEXE=="")
            app.EditField.Value ='invalid Name';
            else
            audiowrite(app.EnteredfilenameandEXE,app.recordsignal,44100);
             app.EditField.Value ='Audio Saved';
            end
        end

        % Callback function
        function EditField_3ValueChanged(app, event)

           
        end

        % Button pushed function: AddNoiseButton
        function AddNoiseButtonPushed(app, event)
         if(app.selectedfile==0)
    app.EditField_3.Value="No File Selected To add noise";
         else
            app.originalAudio=app.selectedfile;
          app.originalAudioFS=app.selectedfileFS;
            
            noise = randn(size(app.originalAudio));
            noisyAudio = app.originalAudio + 5^(10/20) * std(app.originalAudio) * noise;

            audiowrite(app.selectedfilepath, noisyAudio, app.originalAudioFS);
              [app.selectedfile, app.selectedfileFS]=audioread( app.selectedfilenameandEXE); 
               app.selectedfilepath= app.selectedfilenameandEXE;
                app.EditField_3.Value="Noise Added";
         end
        end

        % Button pushed function: RemoveNoiseButton
        function RemoveNoiseButtonPushed(app, event)
             if(app.selectedfile==0)
    app.EditField_3.Value="No File Selected To remove noise";
             elseif(app.originalAudio==0)
               app.EditField_3.Value="There is no noise to remove";  
             else
            audiowrite(app.selectedfilepath, app.originalAudio, app.originalAudioFS);
              [app.selectedfile, app.selectedfileFS]=audioread( app.selectedfilenameandEXE); 
               app.selectedfilepath= app.selectedfilenameandEXE;
               app.EditField_3.Value="Noise Removed";
             end
        end

        % Button pushed function: PlayFileButton
        function PlayFileButtonPushed(app, event)
if(app.selectedfile==0)
    app.EditField_3.Value="No File Selected To play";
else
                 sound(  app.volumefactor*app.selectedfile, app.speedfactor*app.selectedfileFS);
                  app.EditField_3.Value="File is playing";
end                
        end

        % Button pushed function: AddEchoButton
        function AddEchoButtonPushed(app, event)
  if(app.selectedfile==0)
    app.EditField_3.Value="No File Selected To add echo";
         else
            h=[1,zeros(1,0.4*app.selectedfileFS),0.5,zeros(1,0.4*app.selectedfileFS),0.25]; 
   echo=conv(app.selectedfile,h);
   app.originalAudiobeforeecho=app.selectedfile;
    app.originalAudiobeforeechoFS=app.selectedfileFS;
 audiowrite(app.selectedfilepath,echo,app.selectedfileFS);
    [app.selectedfile, app.selectedfileFS]=audioread( app.selectedfilenameandEXE); 
               app.selectedfilepath= app.selectedfilenameandEXE;
                app.EditField_3.Value="Echo Added";
  end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
           if(app.selectedfilenameandEXE=="")
                app.EditField_3.Value="No File Selected";
              app.selectedfile=0;  
           elseif(exist(app.selectedfilenameandEXE, 'file')==0)
                app.EditField_3.Value="This File doesnot exist";
                app.selectedfile=0;
           else
            [app.selectedfile, app.selectedfileFS]=audioread(app.selectedfilenameandEXE); 
               app.selectedfilepath=app.selectedfilenameandEXE;
              
               app.selectedfileoriginal=app.selectedfile;
         app.selectedfileoriginalFS=app.selectedfileFS;
           app.EditField_3.Value="File Selected";
           end
        end

        % Callback function
        function eaglesoundButtonPushed(app, event)
      
        end

        % Callback function
        function confirmAddNoiseButtonPushed(app, event)
           
        end

        % Callback function
        function confirmRemovenoiseButtonPushed(app, event)
          
        end

        % Button pushed function: RemoveEchoButton
        function RemoveEchoButtonPushed(app, event)
            if(app.selectedfile==0)
    app.EditField_3.Value="No File Selected To remove echo";
            elseif(app.originalAudiobeforeecho==0)
               app.EditField_3.Value="There is no echo to remove";
         else
            audiowrite(app.selectedfilepath, app.originalAudiobeforeecho, app.originalAudiobeforeechoFS);
              [app.selectedfile, app.selectedfileFS]=audioread( app.selectedfilenameandEXE);  
              app.selectedfilepath= app.selectedfilenameandEXE;
                app.EditField_3.Value="Echo Removed";
            end
        end

        % Callback function
        function confirmAddEchoButtonPushed(app, event)
          
        end

        % Callback function
        function ConfirmRemoveechoButtonPushed(app, event)
           
        end

        % Callback function
        function SelectSpeedAndPlaySpinnerValueChanged(app, event)
    
        end

        % Value changed function: EnterFileNameEditField
        function EnterFileNameEditFieldValueChanged(app, event)
       app.Enteredfilename = app.EnterFileNameEditField.Value;
       app.EnteredfilenameandEXE = append(app.Enteredfilename,app.constEXE);
        end

        % Value changed function: SelectFileNameEditField
        function SelectFileNameEditFieldValueChanged(app, event)
            app.Enteredfilename = app.SelectFileNameEditField.Value;
             app.selectedfilenameandEXE = append(app.Enteredfilename,app.constEXE);
        end

        % Button pushed function: defualtButton
        function defualtButtonPushed(app, event)
          
                app.speedfactor=1;
                app.EditField_3.Value="Speed is set to defualt";
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            app.speedfactor=2;
             app.EditField_3.Value="Speed is set to 2x";
        end

        % Button pushed function: xButton_3
        function xButton_3Pushed(app, event)
              app.speedfactor=0.5;
                app.EditField_3.Value="Speed is set to 0.5x";
        end

        % Button pushed function: xButton_2
        function xButton_2Pushed(app, event)
            app.speedfactor=4;
              app.EditField_3.Value="Speed is set to 4x";
        end

        % Callback function
        function xButton_4Pushed(app, event)
            
        end

        % Button pushed function: BeepSoundButton
        function BeepSoundButtonPushed(app, event)
fs = 44100;
t = 0:1/fs:1; 
beepsound = sin(2*pi*500*t); 
sound(beepsound, fs);
        end

        % Button pushed function: SubplotAudioSignalButton
        function SubplotAudioSignalButtonPushed(app, event)
            if(app.selectedfile==0)
    app.EditField_3.Value="No File Selected To Subplot";
            else
            time=(0:length(app.volumefactor*app.selectedfile)-1)/( app.speedfactor*app.selectedfileFS);
            subplot(3,1,1)
            plot(time , app.volumefactor*app.selectedfile);
            end
        end

        % Button pushed function: defualtButton_2
        function defualtButton_2Pushed(app, event)
              app.volumefactor=1;
                app.EditField_3.Value="Volume is set to default";
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.volumefactor=1.5;
              app.EditField_3.Value="Volume is set to 150%";
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
              app.volumefactor=0.5;
                app.EditField_3.Value="Volume is set to 50%";
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
              app.volumefactor=2;
                app.EditField_3.Value="Volume is set to 200%";
        end

        % Button pushed function: CarHornSoundButton
        function CarHornSoundButtonPushed(app, event)
              [carhornSound, Fs] = audioread("mixkit-car-double-horn-719.wav");
            sound(carhornSound, Fs);
        end

        % Button pushed function: BirdsSoundButton
        function BirdsSoundButtonPushed(app, event)
             [birdsSound, Fs] = audioread("mixkit-forest-birds-singing-1212.wav");
            sound(birdsSound, Fs);
        end

        % Button pushed function: LionSoundButton
        function LionSoundButtonPushed(app, event)
             [lionSound, Fs] = audioread("mixkit-wild-lion-animal-roar-6(1).wav");
            sound(lionSound, Fs);
        end

        % Callback function
        function highButtonPushed(app, event)
          
        end

        % Callback function
        function lowButtonPushed(app, event)
         
        end

        % Button pushed function: xButton_4
        function xButton_4Pushed2(app, event)
             app.speedfactor=0.75;
               app.EditField_3.Value="Speed is set to 0.75x";
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.volumefactor=0.75;
            app.EditField_3.Value="Volume is set to 0.75x";
        end

        % Button pushed function: singAudioFileButton
        function singAudioFileButtonPushed(app, event)
            [app.sound1, app.sound1Fs] = audioread("sing.wav");
            app.time1=(0:length(app.volumefactor*app.sound1)-1)/( app.speedfactor*app.sound1Fs);
            plot(app.UIAxes_2, app.time1 , app.volumefactor*app.sound1);
            app.EditField_4.Value="signal 1 is ready.sing Audio File selected";
        end

        % Button pushed function: Audio1Button
        function Audio1ButtonPushed(app, event)
             [app.sound2, app.sound2Fs] = audioread();%put whatever song you like here just put the path here
            app.time2=(0:length(app.volumefactor*app.sound2)-1)/( app.speedfactor*app.sound2Fs);
            plot(app.UIAxes, app.time2 , app.volumefactor*app.sound2);
            app.EditField_4.Value="signal 2 is ready.Audio 1 selected";
        end

        % Button pushed function: MultiplyButton
        function MultiplyButtonPushed(app, event)
       if(app.sound1==0 & app.sound2==0)     
       
       app.EditField_4.Value="No signal selected";    
       elseif(app.sound1==0)
            app.EditField_4.Value="Signal 1 Not selected";
       elseif(app.sound2==0)
            app.EditField_4.Value="Signal 2 Not selected";
       else
 sound1time   =size(app.sound1, 1)/app.sound1Fs;
       startTime = 0; 
endTime = sound1time;  


    startIndex = round(startTime * app.sound1Fs) + 1;
    endIndex = round(endTime * app.sound1Fs);
    cutAudioData = app.sound2(startIndex:endIndex,:); 
       
        
            resultSignal = cutAudioData .* app.sound1;
             time3=(0:length(app.volumefactor*resultSignal)-1)/( app.speedfactor*app.sound2Fs);
            plot(app.UIAxes2, time3 , app.volumefactor*resultSignal); 
            sound(3*resultSignal,app.sound1Fs)
            app.EditField_4.Value="signal 3 is ready.Multiplycation is ready";
       end
        end

        % Button pushed function: SubtractButton
        function SubtractButtonPushed(app, event)
       if(app.sound1==0 & app.sound2==0)     
       app.EditField_4.Value="No signal selected";    
       elseif(app.sound1==0)
           app.EditField_4.Value="Signal 1 Not selected";
       elseif(app.sound2==0)
            app.EditField_4.Value="Signal 2 Not selected";
 else
                 
            sound1time   =size(10*app.sound1, 1)/app.sound1Fs;
       startTime = 0; 
endTime = sound1time;  


    startIndex = round(startTime * app.sound1Fs) + 1;
    endIndex = round(endTime * app.sound1Fs);
    cutAudioData = app.sound2(startIndex:endIndex,:); 
       
        
            resultSignal = cutAudioData - 10*app.sound1;
             time3=(0:length(app.volumefactor*resultSignal)-1)/( app.speedfactor*app.sound1Fs);
            plot(app.UIAxes2, time3 , app.volumefactor*resultSignal); 
            sound(resultSignal,app.sound1Fs)
            app.EditField_4.Value="signal 3 is ready.Subtraction is ready";
           
       end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.sound1=0;  
            app.sound2=0;
            
             plot(app.UIAxes2, 0 , 0); 
              plot(app.UIAxes_2, 0 , 0);
               plot(app.UIAxes, 0 , 0);
               app.EditField_4.Value="Signals Reseted";
        end

        % Button pushed function: Audio2Button
        function Audio2ButtonPushed(app, event)
             [app.sound2, app.sound2Fs] = audioread();%put whatever song you like here just put the path here
            app.time2=(0:length(app.volumefactor*app.sound2)-1)/( app.speedfactor*app.sound2Fs);
            plot(app.UIAxes, app.time2 , app.volumefactor*app.sound2);
            app.EditField_4.Value="signal 2 is ready.Audio 2 selected";
        end

        % Button pushed function: Audio3Button
        function Audio3ButtonPushed(app, event)
                [app.sound2, app.sound2Fs] = audioread();%put whatever song you like here just put the path here
            app.time2=(0:length(app.volumefactor*app.sound2)-1)/( app.speedfactor*app.sound2Fs);
            plot(app.UIAxes, app.time2 , app.volumefactor*app.sound2);
            app.EditField_4.Value="signal 2 is ready.Audio 3 selected";
        end

        % Button pushed function: Audio4Button
        function Audio4ButtonPushed(app, event)
                [app.sound2, app.sound2Fs] = audioread();%put whatever song you like here just put the path here
            app.time2=(0:length(app.volumefactor*app.sound2)-1)/( app.speedfactor*app.sound2Fs);
            plot(app.UIAxes, app.time2 , app.volumefactor*app.sound2);
            app.EditField_4.Value="signal 2 is ready.Audio 4 selected";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RandomNoisesTab = uitab(app.TabGroup);
            app.RandomNoisesTab.Title = 'Random Noises';
            app.WelcomeToOurAudioEditorLabel = uilabel(app.RandomNoisesTab);
            app.WelcomeToOurAudioEditorLabel.Text = 'Welcome To Our Audio Editor';
            app.ChooseWhatYouWantToDoLabel = uilabel(app.RandomNoisesTab);
            app.ChooseWhatYouWantToDoLabel.Text = 'Choose What You Want To Do';
            app.WhiteNoiseSoundButton = uibutton(app.RandomNoisesTab, 'push');
            app.WhiteNoiseSoundButton.ButtonPushedFcn = createCallbackFcn(app, @WhiteNoiseSoundButtonPushed, true);
            app.WhiteNoiseSoundButton.Text = 'White Noise Sound';
            app.NotficationsoundButton = uibutton(app.RandomNoisesTab, 'push');
            app.NotficationsoundButton.ButtonPushedFcn = createCallbackFcn(app, @NotficationsoundButtonPushed, true);
            app.NotficationsoundButton.Text = {'Notfication sound'; ''};
            app.EaglesoundButton = uibutton(app.RandomNoisesTab, 'push');
            app.EaglesoundButton.ButtonPushedFcn = createCallbackFcn(app, @EaglesoundButtonPushed, true);
            app.EaglesoundButton.Text = 'Eagle sound';
            app.ExitButton = uibutton(app.RandomNoisesTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.BeepSoundButton = uibutton(app.RandomNoisesTab, 'push');
            app.BeepSoundButton.ButtonPushedFcn = createCallbackFcn(app, @BeepSoundButtonPushed, true);
            app.BeepSoundButton.Text = 'Beep Sound';
            app.CarHornSoundButton = uibutton(app.RandomNoisesTab, 'push');
            app.CarHornSoundButton.ButtonPushedFcn = createCallbackFcn(app, @CarHornSoundButtonPushed, true);
            app.CarHornSoundButton.Text = 'Car Horn Sound';
            app.BirdsSoundButton = uibutton(app.RandomNoisesTab, 'push');
            app.BirdsSoundButton.ButtonPushedFcn = createCallbackFcn(app, @BirdsSoundButtonPushed, true);
            app.BirdsSoundButton.Text = 'Birds Sound';
            app.LionSoundButton = uibutton(app.RandomNoisesTab, 'push');
            app.LionSoundButton.ButtonPushedFcn = createCallbackFcn(app, @LionSoundButtonPushed, true);
            app.LionSoundButton.Text = 'Lion Sound';
            app.BeshoGamalLabel = uilabel(app.RandomNoisesTab);
            app.BeshoGamalLabel.Text = 'Besho Gamal';
            app.Image = uiimage(app.RandomNoisesTab);
            app.Image.ImageSource = '360_F_85619893_qcV9Vr8GQGGToKKozmKZlon9M1rNwWNd.jpg';
            app.AudioFilesTab = uitab(app.TabGroup);
            app.AudioFilesTab.Title = 'Audio Files';
            app.RecordingPanel = uipanel(app.AudioFilesTab);
            app.RecordingPanel.Title = 'Recording';
            app.RecordAudioButton = uibutton(app.RecordingPanel, 'push');
            app.RecordAudioButton.ButtonPushedFcn = createCallbackFcn(app, @RecordAudioButtonPushed, true);
            app.RecordAudioButton.Text = 'Record Audio';
            app.playButton = uibutton(app.RecordingPanel, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = 'play';
            app.EditField_2 = uieditfield(app.RecordingPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EnterTimeOfRecordingLabel = uilabel(app.RecordingPanel);
            app.EnterTimeOfRecordingLabel.Text = 'Enter Time Of Recording :';
            app.SaveAudioButton = uibutton(app.RecordingPanel, 'push');
            app.SaveAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAudioButtonPushed, true);
            app.SaveAudioButton.Text = 'Save Audio';
            app.EnterFileNameEditField = uieditfield(app.RecordingPanel, 'text');
            app.EnterFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @EnterFileNameEditFieldValueChanged, true);
            app.EnterFileNameLabel = uilabel(app.RecordingPanel);
            app.EnterFileNameLabel.Text = 'Enter File Name:';
            app.EditField = uieditfield(app.RecordingPanel, 'text');
            app.CoustumizeAudioPanel = uipanel(app.AudioFilesTab);
            app.CoustumizeAudioPanel.Title = 'Coustumize Audio';
            app.AddNoiseButton = uibutton(app.CoustumizeAudioPanel, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.AddNoiseButton.Text = 'Add Noise';
            app.RemoveNoiseButton = uibutton(app.CoustumizeAudioPanel, 'push');
            app.RemoveNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNoiseButtonPushed, true);
            app.RemoveNoiseButton.Text = 'Remove Noise';
            app.PlayFileButton = uibutton(app.CoustumizeAudioPanel, 'push');
            app.PlayFileButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFileButtonPushed, true);
            app.PlayFileButton.Text = 'Play File';
            app.AddEchoButton = uibutton(app.CoustumizeAudioPanel, 'push');
            app.AddEchoButton.ButtonPushedFcn = createCallbackFcn(app, @AddEchoButtonPushed, true);
            app.AddEchoButton.Text = 'Add Echo';
            app.SelectButton = uibutton(app.CoustumizeAudioPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.RemoveEchoButton = uibutton(app.CoustumizeAudioPanel, 'push');
            app.RemoveEchoButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveEchoButtonPushed, true);
            app.RemoveEchoButton.Text = 'Remove Echo';
            app.xButton_2 = uibutton(app.CoustumizeAudioPanel, 'push');
            app.xButton_2.ButtonPushedFcn = createCallbackFcn(app, @xButton_2Pushed, true);
            app.xButton_2.Text = '4x';
            app.xButton_3 = uibutton(app.CoustumizeAudioPanel, 'push');
            app.xButton_3.ButtonPushedFcn = createCallbackFcn(app, @xButton_3Pushed, true);
            app.xButton_3.Text = '0.5x';
            app.xButton = uibutton(app.CoustumizeAudioPanel, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = '2x';
            app.defualtButton = uibutton(app.CoustumizeAudioPanel, 'push');
            app.defualtButton.ButtonPushedFcn = createCallbackFcn(app, @defualtButtonPushed, true);
            app.defualtButton.Text = 'defualt';
            app.ChooseSpeedLabel = uilabel(app.CoustumizeAudioPanel);
            app.ChooseSpeedLabel.Text = 'Choose Speed';
            app.SubplotAudioSignalButton = uibutton(app.CoustumizeAudioPanel, 'push');
            app.SubplotAudioSignalButton.ButtonPushedFcn = createCallbackFcn(app, @SubplotAudioSignalButtonPushed, true);
            app.SubplotAudioSignalButton.Text = 'Subplot Audio Signal';
            app.ChooseVolumeLabel = uilabel(app.CoustumizeAudioPanel);
            app.ChooseVolumeLabel.Text = 'Choose Volume';
            app.Button = uibutton(app.CoustumizeAudioPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '50%';
            app.defualtButton_2 = uibutton(app.CoustumizeAudioPanel, 'push');
            app.defualtButton_2.ButtonPushedFcn = createCallbackFcn(app, @defualtButton_2Pushed, true);
            app.defualtButton_2.Text = 'defualt';
            app.Button_2 = uibutton(app.CoustumizeAudioPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '150%';
            app.Button_3 = uibutton(app.CoustumizeAudioPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '200%';
            app.xButton_4 = uibutton(app.CoustumizeAudioPanel, 'push');
            app.xButton_4.ButtonPushedFcn = createCallbackFcn(app, @xButton_4Pushed2, true);
            app.xButton_4.Text = '0.75x';
            app.Button_4 = uibutton(app.CoustumizeAudioPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '75%';
            app.SelectFileNameLabel = uilabel(app.CoustumizeAudioPanel);
            app.SelectFileNameLabel.Text = 'Select File Name:';
            app.EditField_3 = uieditfield(app.CoustumizeAudioPanel, 'text');
            app.SelectFileNameEditField = uieditfield(app.CoustumizeAudioPanel, 'text');
            app.SelectFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @SelectFileNameEditFieldValueChanged, true);
            app.SignalsTab = uitab(app.TabGroup);
            app.SignalsTab.Title = 'Signals';
            app.MergeSignalsLabel = uilabel(app.SignalsTab);
            app.MergeSignalsLabel.Text = 'Merge Signals:';
            app.signal1Label = uilabel(app.SignalsTab);
            app.signal1Label.Text = 'signal 1:';
            app.signal2Label = uilabel(app.SignalsTab);
            app.signal2Label.Text = 'signal 2:';
            app.UIAxes = uiaxes(app.SignalsTab);
            title(app.UIAxes, 'Signal 2')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.SignalsTab);
            title(app.UIAxes_2, 'Signal 1')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes2 = uiaxes(app.SignalsTab);
            title(app.UIAxes2, 'Signal 3')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.singAudioFileButton = uibutton(app.SignalsTab, 'push');
            app.singAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @singAudioFileButtonPushed, true);
            app.singAudioFileButton.Text = 'sing Audio File';
            app.Audio1Button = uibutton(app.SignalsTab, 'push');
            app.Audio1Button.ButtonPushedFcn = createCallbackFcn(app, @Audio1ButtonPushed, true);
            app.Audio1Button.Text = 'Audio 1 ';
            app.MultiplyButton = uibutton(app.SignalsTab, 'push');
            app.MultiplyButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplyButtonPushed, true);
            app.MultiplyButton.Text = 'Multiply';
            app.SubtractButton = uibutton(app.SignalsTab, 'push');
            app.SubtractButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractButtonPushed, true);
            app.SubtractButton.Text = 'Subtract';
            app.EditField_4 = uieditfield(app.SignalsTab, 'text');
            app.ResetButton = uibutton(app.SignalsTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Audio2Button = uibutton(app.SignalsTab, 'push');
            app.Audio2Button.ButtonPushedFcn = createCallbackFcn(app, @Audio2ButtonPushed, true);
            app.Audio2Button.Text = 'Audio 2';
            app.NoteTouseitrecordaudiowithnamesingLabel = uilabel(app.SignalsTab);
            app.NoteTouseitrecordaudiowithnamesingLabel.Text = {'Note:To use it record audio '; 'with name sing.'};
            app.Audio3Button = uibutton(app.SignalsTab, 'push');
            app.Audio3Button.ButtonPushedFcn = createCallbackFcn(app, @Audio3ButtonPushed, true);
            app.Audio3Button.Text = 'Audio 3';
            app.Audio4Button = uibutton(app.SignalsTab, 'push');
            app.Audio4Button.ButtonPushedFcn = createCallbackFcn(app, @Audio4ButtonPushed, true);
            app.Audio4Button.Text = 'Audio 4';
            app.LettheresultsbeasurpriseLabel = uilabel(app.SignalsTab);
            app.LettheresultsbeasurpriseLabel.Text = 'Let the results be a surprise!!!!!.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end